classdef ResilienceApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout3                     matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        MapControlsTab                  matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        DataPanel                       matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        DataTree                        matlab.ui.container.CheckBoxTree
        PointSourcesNode                matlab.ui.container.TreeNode
        PowerPlantsNode                 matlab.ui.container.TreeNode
        CementPlantsNode                matlab.ui.container.TreeNode
        EthanolPlantsNode               matlab.ui.container.TreeNode
        IronandSteelPlantsNode          matlab.ui.container.TreeNode
        CCSInfrastructureNode           matlab.ui.container.TreeNode
        PipelinesNode                   matlab.ui.container.TreeNode
        OperationalPipelinesNode        matlab.ui.container.TreeNode
        PlannedPipelinesNode            matlab.ui.container.TreeNode
        InjectionsitesNode              matlab.ui.container.TreeNode
        GeologicalreservoirsNode        matlab.ui.container.TreeNode
        NaturalHazardsNode              matlab.ui.container.TreeNode
        PresentNode                     matlab.ui.container.TreeNode
        EarthquakeNode                  matlab.ui.container.TreeNode
        DroughtNode                     matlab.ui.container.TreeNode
        HurricaneNode                   matlab.ui.container.TreeNode
        RiverineFloodingNode            matlab.ui.container.TreeNode
        StrongWindNode                  matlab.ui.container.TreeNode
        WildfireNode                    matlab.ui.container.TreeNode
        Projected2050Node               matlab.ui.container.TreeNode
        MaxSummerDailyAvgTemperatureFNode  matlab.ui.container.TreeNode
        RCP85Node_2                     matlab.ui.container.TreeNode
        ConsecutiveDayswithoutPrecipitationNode  matlab.ui.container.TreeNode
        RCP45Node                       matlab.ui.container.TreeNode
        RCP85Node                       matlab.ui.container.TreeNode
        TotalAnnualPrecipitationinNode  matlab.ui.container.TreeNode
        RCP45Node_2                     matlab.ui.container.TreeNode
        RCP85Node_3                     matlab.ui.container.TreeNode
        GridCarbonIntensitylbsCO2MWhNode  matlab.ui.container.TreeNode
        current2021Node                 matlab.ui.container.TreeNode
        projected2050Node               matlab.ui.container.TreeNode
        NDVINode                        matlab.ui.container.TreeNode
        SocialVulnerabilityNode         matlab.ui.container.TreeNode
        CommunityResilienceNode         matlab.ui.container.TreeNode
        PopulationNode                  matlab.ui.container.TreeNode
        InterstatesNode                 matlab.ui.container.TreeNode
        RailNode                        matlab.ui.container.TreeNode
        ElectrictransmissionlinesNode   matlab.ui.container.TreeNode
        DisadvantagedCommunitiesNode    matlab.ui.container.TreeNode
        DACCEQNode                      matlab.ui.container.TreeNode
        DACtribaloverlapCEQNode         matlab.ui.container.TreeNode
        DACScoreDOENode                 matlab.ui.container.TreeNode
        TCTACsNode                      matlab.ui.container.TreeNode
        TCTACRegionsNode                matlab.ui.container.TreeNode
        TCTACAwardeesNode               matlab.ui.container.TreeNode
        TCTACPartnersNode               matlab.ui.container.TreeNode
        CheckproximitytoDACPanel        matlab.ui.container.Panel
        GridLayout2_2                   matlab.ui.container.GridLayout
        CheckLocationButton             matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        latoLabel                       matlab.ui.control.Label
        Edit_lat                        matlab.ui.control.EditField
        lonoLabel                       matlab.ui.control.Label
        radiusmiLabel                   matlab.ui.control.Label
        Edit_rad                        matlab.ui.control.EditField
        Edit_lon                        matlab.ui.control.EditField
        FilterVegetationIndexPanel      matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        UpdateNDVIButton                matlab.ui.control.Button
        DropDown_NatHazLvlNDVI          matlab.ui.control.DropDown
        DropDown_NatHazNDVI             matlab.ui.control.DropDown
        NaturalHazardRiskNDVIButton     matlab.ui.control.StateButton
        FilterPointSourcesPanel         matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        DropDown_PopGTLT                matlab.ui.control.DropDown
        Label_6                         matlab.ui.control.Label
        peopleLabel                     matlab.ui.control.Label
        MTCO_2eaLabel                   matlab.ui.control.Label
        milesLabel                      matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Edit_Population                 matlab.ui.control.EditField
        Edit_Emissions                  matlab.ui.control.EditField
        Edit_DistInj                    matlab.ui.control.EditField
        DropDown_NatHazLvlPntSrc        matlab.ui.control.DropDown
        DropDown_NatHazPntSrc           matlab.ui.control.DropDown
        UpdatePointSourcesButton        matlab.ui.control.Button
        PopulationButton                matlab.ui.control.StateButton
        EmissionsButton                 matlab.ui.control.StateButton
        NaturalHazardRiskPntSrcButton   matlab.ui.control.StateButton
        DistancetoInjectionButton       matlab.ui.control.StateButton
        DataLayersTab                   matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        SourcesPanel                    matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        TextArea                        matlab.ui.control.TextArea
        DataLayersPanel                 matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        Tree                            matlab.ui.container.Tree
        PointSourcesNode_2              matlab.ui.container.TreeNode
        PowerPlantsNode_2               matlab.ui.container.TreeNode
        CementPlantsNode_2              matlab.ui.container.TreeNode
        EthanolPlantsNode_2             matlab.ui.container.TreeNode
        IronandSteelPlantsNode_2        matlab.ui.container.TreeNode
        CCSInfrastructureNode_2         matlab.ui.container.TreeNode
        PipelinesNode_2                 matlab.ui.container.TreeNode
        OperationalNode                 matlab.ui.container.TreeNode
        PlannedNode                     matlab.ui.container.TreeNode
        InjectionSitesNode              matlab.ui.container.TreeNode
        GeologicalReservoirsNode        matlab.ui.container.TreeNode
        NaturalHazardsNode_2            matlab.ui.container.TreeNode
        PresentNode_2                   matlab.ui.container.TreeNode
        EarthquakeNode_2                matlab.ui.container.TreeNode
        DroughtNode_2                   matlab.ui.container.TreeNode
        HurricaneNode_2                 matlab.ui.container.TreeNode
        RiverineFloodingNode_2          matlab.ui.container.TreeNode
        StrongWindNode_2                matlab.ui.container.TreeNode
        WildfireNode_2                  matlab.ui.container.TreeNode
        Projected2050Node_2             matlab.ui.container.TreeNode
        MaxSummerDailyAvgTemperatureNode  matlab.ui.container.TreeNode
        RCP85Node_4                     matlab.ui.container.TreeNode
        ConsecutiveDayswithoutPrecipitationNode_2  matlab.ui.container.TreeNode
        RCP45Node_3                     matlab.ui.container.TreeNode
        RCP85Node_5                     matlab.ui.container.TreeNode
        TotalAnnualPrecipitationNode    matlab.ui.container.TreeNode
        RCP45Node_4                     matlab.ui.container.TreeNode
        RCP85Node_6                     matlab.ui.container.TreeNode
        GridCarbonIntensitylbsCO2MWhNode_2  matlab.ui.container.TreeNode
        PresentNode_3                   matlab.ui.container.TreeNode
        Projected2050Node_3             matlab.ui.container.TreeNode
        NDVINode_2                      matlab.ui.container.TreeNode
        SocialVulnerabilityNode_2       matlab.ui.container.TreeNode
        CommunityResilienceNode_2       matlab.ui.container.TreeNode
        PopulationNode_2                matlab.ui.container.TreeNode
        InterstatesNode_2               matlab.ui.container.TreeNode
        RailNode_2                      matlab.ui.container.TreeNode
        DisadvantagedCommunitiesNode_2  matlab.ui.container.TreeNode
        DACCEQNode_2                    matlab.ui.container.TreeNode
        DACtribaloverlapCEQNode_2       matlab.ui.container.TreeNode
        DACScoreDOENode_2               matlab.ui.container.TreeNode
        TCTACsNode_2                    matlab.ui.container.TreeNode
        TCTACRegionsNode_2              matlab.ui.container.TreeNode
        TCTACAwardeesNode_2             matlab.ui.container.TreeNode
        TCTACPartnersNode_2             matlab.ui.container.TreeNode
        DACTab                          matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        FreezemapHoverforDACdetailsButton  matlab.ui.control.StateButton
        DAC_printout                    matlab.ui.control.TextArea
        Label_7                         matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
    end


    properties (Access = private)
        ax  % Description
        Hyperlink                       matlab.ui.control.Hyperlink
    end


    methods (Access = private)

        function hover(app)

            x = app.ax.CurrentPoint(1,1);
            y = app.ax.CurrentPoint(1,2);

            mouse = mappointshape(x,y);
            mouse.ProjectedCRS = projcrs(3857);

            regions_ind = (app.DACScoreDOENode.NodeData.lat1<x) & (app.DACScoreDOENode.NodeData.lat2>x) &...
                (app.DACScoreDOENode.NodeData.lon1<y) & (app.DACScoreDOENode.NodeData.lon2>y);
            regions = find(regions_ind);
            n = length(regions);
            if n>0
                if n==1
                    ind = regions;
                else
                    for i = 1:n
                        try
                            inside = isinterior(app.DACScoreDOENode.NodeData(regions(i),:).Shape,mouse);
                        catch
                            inside = false;
                        end
                        if inside
                            ind = regions(i);
                            break
                        end
                    end
                end
                if exist('ind','var')
                    DAC_attribute_str = ...
                        sprintf(['%s\nPopulation: %d\nDAC Score: %0.2f\nEnergy burden: %0.2f\n' ...
                        'Non-grid-connected heating fuel: %0.2f\nOutage duration: %0.2f\n' ...
                        'Outage events: %0.2f\nTransportation Costs: %0.2f\nCancer risk: %0.2f\n' ...
                        'Climate hazards loss of life estimates: %0.2f\nDiesel: %0.2f\n' ...
                        'Homes build before 1960: %0.2f\nNPL proximity: %0.2f\nPM25: %0.2f\n' ...
                        'RMP proximity: %0.2f\nTraffic proximity: %0.2f\n' ...
                        'TSDF proximity: %0.2f\nWater discharge: %0.2f\n' ...
                        '>30 min commute: %0.2f\nDisabled population: %0.2f\n' ...
                        'Food desert: %0.2f\nHomelessness: %0.2f\n' ...
                        'Housing Costs: %0.2f\nIncomplete plumbing: %0.2f\n' ...
                        'Internet access: %0.2f\nJob Access: %0.2f\n' ...
                        'Less HS education: %0.2f\nLinguistic isolation: %0.2f\n' ...
                        'Low income population: %0.2f\nMobile home: %0.2f\n' ...
                        'No vehicle: %0.2f\nParks: %0.2f\n' ...
                        'Population 65 and older: %0.2f\nRenters: %0.2f\n' ...
                        'Single parent: %0.2f\nUnemployed: %0.2f\n' ...
                        'Uninsured: %0.2f\nCoal employment: %0.2f\n' ...
                        'Fossil energy employment: %0.2f\n' ...
                        'National ranking: %0.2f\nState Ranking: %0.2f'],...
                        app.DACScoreDOENode.NodeData.county{ind},...
                        app.DACScoreDOENode.NodeData.population(ind),...
                        app.DACScoreDOENode.NodeData.DACSCORE(ind),...
                        app.DACScoreDOENode.NodeData.energybrdn(ind),...
                        app.DACScoreDOENode.NodeData.nongridpct(ind),...
                        app.DACScoreDOENode.NodeData.outagedur(ind),...
                        app.DACScoreDOENode.NodeData.outagecnt(ind),...
                        app.DACScoreDOENode.NodeData.trnsptbrdn(ind),...
                        app.DACScoreDOENode.NodeData.cancer(ind),...
                        app.DACScoreDOENode.NodeData.eal_npctl(ind),...
                        app.DACScoreDOENode.NodeData.dslpm(ind),...
                        app.DACScoreDOENode.NodeData.pre1960pct(ind),...
                        app.DACScoreDOENode.NodeData.pnpl(ind),...
                        app.DACScoreDOENode.NodeData.pm25(ind),...
                        app.DACScoreDOENode.NodeData.prmp(ind),...
                        app.DACScoreDOENode.NodeData.ptraf(ind),...
                        app.DACScoreDOENode.NodeData.ptsdf(ind),...
                        app.DACScoreDOENode.NodeData.wdis(ind),...
                        app.DACScoreDOENode.NodeData.nointpct(ind),...
                        app.DACScoreDOENode.NodeData.disablpct(ind),...
                        app.DACScoreDOENode.NodeData.foodsrtpct(ind),...
                        app.DACScoreDOENode.NodeData.homelespct(ind),...
                        app.DACScoreDOENode.NodeData.housebrdn(ind), ...
                        app.DACScoreDOENode.NodeData.incplmbpct(ind), ...
                        app.DACScoreDOENode.NodeData.longcompct(ind), ...
                        app.DACScoreDOENode.NodeData.jobaccess(ind), ...
                        app.DACScoreDOENode.NodeData.lesshspct(ind), ...
                        app.DACScoreDOENode.NodeData.lingisopct(ind), ...
                        app.DACScoreDOENode.NodeData.lowincfpct(ind), ...
                        app.DACScoreDOENode.NodeData.mobilhmpct(ind), ...
                        app.DACScoreDOENode.NodeData.nocarpct(ind), ...
                        app.DACScoreDOENode.NodeData.greenspace(ind), ...
                        app.DACScoreDOENode.NodeData.over64pct(ind), ...
                        app.DACScoreDOENode.NodeData.rentpct(ind), ...
                        app.DACScoreDOENode.NodeData.snglpntpct(ind), ...
                        app.DACScoreDOENode.NodeData.unemppct(ind), ...
                        app.DACScoreDOENode.NodeData.uninsurpct(ind), ...
                        app.DACScoreDOENode.NodeData.coalemplmt(ind), ...
                        app.DACScoreDOENode.NodeData.fossemplmt(ind), ...
                        app.DACScoreDOENode.NodeData.NATLRANK(ind), ...
                        app.DACScoreDOENode.NodeData.STATERANK(ind));
                    app.DAC_printout.Value = DAC_attribute_str;
                end

            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            clc;close all

            % initialize map
            gx = geoaxes(app.Panel, 'Basemap', 'darkwater', 'NextPlot', 'add');
            gx.Title.FontName = 'Helvetica';
            gx.Title.String = 'MCSC Interactive Geospatial Mapping Tool'; % display label, come up with better name
            gx.Title.FontSize = 18;
            gx.Subtitle.String = sprintf('An interactive map to support climate resilience business decisions');
            gx.Subtitle.FontName = 'Helvetica';
            gx.Subtitle.FontSize = 16;
            gx.FontSize = 16;
            gx.Subtitle.FontAngle = 'italic';
            gx.Scalebar.Visible = 'on'; % display scale bar
            gx.Grid = "off"; % no grid (may be distracting)
            % add option to turn grid on
            gx.LatitudeLabel.FontName = 'Helvetica';
            gx.LatitudeLabel.FontSize = 16;
            gx.LongitudeLabel.FontName = 'Helvetica';
            gx.LongitudeLabel.FontSize = 16;
            tlbr = axtoolbar(gx, {'export', 'datacursor', 'stepzoomin', 'stepzoomout', 'restoreview'}); % add differfent options to map toolbar
            addToolbarMapButton(tlbr, "basemap"); % allow user to choose different basemaps for personalized visualization
            geolimits(gx, [10 70], [-180 -30]); % map shows entirety of the USA
            hold(gx,'on')
            drawnow
            legend(gx,'-dynamiclegend','Tag','legend')
            enableDefaultInteractivity(gx)

            % load data into nodes
            app = loadData(app);

            if isempty(app.ax)
                if ~isempty(app.Panel.Children)
                    for i = 1:length(app.Panel.Children)
                        if isa(app.Panel.Children(i),'matlab.graphics.axis.GeographicAxes')
                            app.ax = app.Panel.Children(i);
                        end
                    end
                end
            end

        end

        % Callback function: DataTree
        function checkchange(app, event)
            checkedNodes = app.DataTree.CheckedNodes;
            if ismember(event.Source.SelectedNodes,event.Source.CheckedNodes) % the user 'turned on' a data layer => plot it
                for i = 1:length(event.Source.CheckedNodes)
                    if ~isempty(event.Source.CheckedNodes(i).NodeData) % if the checked node has a populated NodeData field
                        if  length(app.ax.Children)<1
                            switch event.Source.CheckedNodes(i).NodeData.Shape(1).Geometry % select the first shape file to determine the type of shape
                                case "point"
                                    pointLayer(app.ax, event.Source.CheckedNodes(i))
                                case "line"
                                    lineLayer(app.ax, event.Source.CheckedNodes(i))
                                case "polygon"
                                    polyLayer(app.ax, event.Source.CheckedNodes(i))
                            end
                        else
                            % MATLAB finds another child of app.ax that is
                            % class 'map.graphics.primitive.Data' without a
                            % tag, so this is a fix
                            for k = 1:length(app.ax.Children); Tag_array{k} = app.ax.Children(k).Tag;end

                            if ~ismember(event.Source.CheckedNodes(i).Tag,Tag_array) % if the checked node is not already plotted
                                % (if Children is not defined because there are no layers on the map, this statement
                                % generates an error even if it is the second condition of and OR with the first if branch being hte first condition
                                switch event.Source.CheckedNodes(i).NodeData.Shape(1).Geometry % select the first shape file to determine the type of shape
                                    case "point"
                                        pointLayer(app.ax, event.Source.CheckedNodes(i))
                                    case "line"
                                        lineLayer(app.ax, event.Source.CheckedNodes(i))
                                    case "polygon"
                                        polyLayer(app.ax, event.Source.CheckedNodes(i))
                                end
                            end
                        end
                    end
                end
            else % the user de-selected a node => remove the plot
                objs = get(app.ax, 'Children');
                if ~isempty(objs)
                    % get ids of leaf nodes that are no longer checked
                    ids_nodes_2_del = ~ismember(event.PreviousLeafCheckedNodes,event.LeafCheckedNodes);
                    % get ids of the plotted objects to be deleted
                    objs_cell = num2cell(objs);
                    obj_tag_cell = cellfun(@(x) x.Tag,objs_cell,'UniformOutput',false);
                    ids_objs_2_del   = ismember(obj_tag_cell,{event.PreviousLeafCheckedNodes(ids_nodes_2_del).Text});
                    delete(objs(ids_objs_2_del))
                end
            end

        end

        % Callback function
        function resetMapSize(app, event)
            if isempty(app.ax)
                if ~isempty(app.Panel.Children)
                    for i = 1:length(app.Panel.Children)
                        if isa(app.Panel.Children(i),'matlab.graphics.axis.GeographicAxes')
                            app.ax = app.Panel.Children(i);
                        end
                    end
                end
            else
                app.ax.Units = 'normalized';
                app.ax.Position = [0.1 0.1 0.8 0.8];
            end

        end

        % Button pushed function: UpdatePointSourcesButton
        function filter_pt_src(app, event)
            inds_pt_src = find(ismember(app.DataTree.CheckedNodes, app.PointSourcesNode.Children));
            if ~isempty(inds_pt_src)% if at least one point source is checked
                for j = 1:length(inds_pt_src)
                    PntSrc_name = app.DataTree.CheckedNodes(inds_pt_src(j)).Text;

                    if app.DistancetoInjectionButton.Value
                        % distance from injection site filter
                        ids_2_plot_dist = filter_by_distance(PntSrc_name,str2double(app.Edit_DistInj.Value));
                    else
                        ids_2_plot_dist = true(size(app.DataTree.CheckedNodes(inds_pt_src(j)).NodeData,1),1);
                    end

                    if app.NaturalHazardRiskPntSrcButton.Value
                        % natural hazard filter
                        NatHaz = app.DropDown_NatHazPntSrc.Value;
                        NatHaz_lvl = app.DropDown_NatHazLvlPntSrc.Value;
                        [ids_2_plot_nathaz] = filter_by_nat_haz_pop(app,PntSrc_name,NatHaz,NatHaz_lvl);
                    else
                        ids_2_plot_nathaz = true(size(app.DataTree.CheckedNodes(inds_pt_src(j)).NodeData,1),1);
                    end

                    if app.EmissionsButton.Value
                        % emissions filter
                        [ids_2_plot_emissions] = filter_by_emissions(app,PntSrc_name,str2double(app.Edit_Emissions.Value));
                    else
                        ids_2_plot_emissions = true(size(app.DataTree.CheckedNodes(inds_pt_src(j)).NodeData,1),1);
                    end

                    if app.PopulationButton.Value
                        % population filter
                        pop_lvl = str2double(app.Edit_Population.Value);
                        [ids_2_plot_pop] = filter_by_nat_haz_pop(app,PntSrc_name,'Population',pop_lvl);
                    else
                        ids_2_plot_pop = true(size(app.DataTree.CheckedNodes(inds_pt_src(j)).NodeData,1),1);
                    end


                    ids_2_plot = ids_2_plot_dist & ids_2_plot_nathaz & ids_2_plot_emissions & ids_2_plot_pop;


                    objs = get(app.ax, 'Children');
                    objs_cell = num2cell(objs);
                    obj_tag_cell = cellfun(@(x) x.Tag,objs_cell,'UniformOutput',false);
                    ids_objs_2_del   = ismember(obj_tag_cell,app.DataTree.CheckedNodes(inds_pt_src(j)).Text);
                    delete(objs(ids_objs_2_del))

                    pointLayer(app.ax, app.DataTree.CheckedNodes(inds_pt_src(j)),ids_2_plot)
                end
            end


        end

        % Button pushed function: UpdateNDVIButton
        function filter_NDVI(app, event)
            ids_2_plot_NDVI = filter_NDVI_nathaz(app,app.DropDown_NatHazNDVI.Value,app.DropDown_NatHazLvlNDVI.Value);

            objs = get(app.ax, 'Children');
            objs_cell = num2cell(objs);
            obj_tag_cell = cellfun(@(x) x.Tag,objs_cell,'UniformOutput',false);
            ids_objs_2_del   = ismember(obj_tag_cell,'NDVI');
            delete(objs(ids_objs_2_del))

            polyLayer(app.ax, app.NDVINode,ids_2_plot_NDVI);

            if ~isempty(app.DataTree.CheckedNodes)
                if ~any(ismember({app.DataTree.CheckedNodes.Tag},'NDVI'))
                    app.DataTree.CheckedNodes = [app.DataTree.CheckedNodes; app.NDVINode];
                end
            else
                app.DataTree.CheckedNodes = [app.DataTree.CheckedNodes; app.NDVINode];
            end
        end

        % Button pushed function: CheckLocationButton
        function check_location(app, event)
            % clear previous circle
            ClearCircle(app)

            mi2deg = @(x)x/69; % convert miles to degrees
            r = mi2deg(str2double(app.Edit_rad.Value));
            x = str2double(app.Edit_lon.Value);
            y = str2double(app.Edit_lat.Value);
            geolimits(app.ax, [y-2*r y+2*r], [x-2*r x+2*r]);

            % add red dot at point of interest
            geoplot(app.ax,y,x,'ro','markerfacecolor','r','markersize',10,'Tag','point_of_interest','Displayname','Point of Interest');

            % add circle at radius of interest
            th = linspace(0,2*pi);
            geoplot(app.ax,r*sin(th)+y,r*cos(th)+x,'r','linewidth',2,'Tag','radius_of_interest','Displayname','Radius of Interest');

            for k = 1:length(app.ax.Children); Tag_array{k} = app.ax.Children(k).Tag;end

            % if DAC are not already plotted, plot them
            if ~any(strcmp(Tag_array,app.DACScoreDOENode.Text))
                polyLayer(app.ax, app.DACScoreDOENode)
                app.DataTree.CheckedNodes = [app.DataTree.CheckedNodes; app.DACScoreDOENode];
            end

        end

        % Button pushed function: ClearButton
        function ClearCircle(app, event)
            objs = get(app.ax, 'Children');
            objs_cell = num2cell(objs);
            obj_tag_cell = cellfun(@(x) x.Tag,objs_cell,'UniformOutput',false);
            ids_objs_2_del   = ismember(obj_tag_cell,{'point_of_interest','radius_of_interest'});
            delete(objs(ids_objs_2_del))
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            objs = get(app.GridLayout8);
            if ismember(app.Hyperlink, objs.Children)
                delete(app.Hyperlink)
            elseif ~isempty(app.TextArea)
                app.TextArea.Value = '';
            end
            if ~isempty(event.SelectedNodes.NodeData)
                app.Hyperlink = uihyperlink(app.GridLayout8);
                app.Hyperlink.Layout.Row = 2;
                app.Hyperlink.Layout.Column = 1;
                app.Hyperlink.WordWrap = 'on';
                app.Hyperlink.URL =  event.SelectedNodes.NodeData(1,1);
                app.Hyperlink.Text = event.SelectedNodes.NodeData(2,1);
                app.TextArea.Value = event.SelectedNodes.NodeData(3,1);
            else
                app.TextArea.Value = 'Select a data layer wihtin this category to see source information';
            end
        end

        % Value changed function: FreezemapHoverforDACdetailsButton
        function FreezemapHoverforDACdetailsButtonValueChanged(app, event)
            value = app.FreezemapHoverforDACdetailsButton.Value;

            if ~isempty(app.DataTree.CheckedNodes)
                if ~any(ismember({app.DataTree.CheckedNodes.Tag},'DAC Score (DOE)'))
                    app.DataTree.CheckedNodes = [app.DataTree.CheckedNodes; app.DACScoreDOENode];
                    polyLayer(app.ax,app.DACScoreDOENode)
                end
            else
                app.DataTree.CheckedNodes = [app.DataTree.CheckedNodes; app.DACScoreDOENode];
                polyLayer(app.ax,app.DACScoreDOENode)
            end


            if value
                app.UIFigure.WindowButtonMotionFcn = @(~,~) hover(app);
            else
                app.UIFigure.WindowButtonMotionFcn = [];
                enableDefaultInteractivity(app.ax)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout3 = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout3);
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.MapControlsTab = uitab(app.TabGroup);
            app.MapControlsTab.Title = 'Map Controls';
            app.GridLayout4 = uigridlayout(app.MapControlsTab);
            app.FilterPointSourcesPanel = uipanel(app.GridLayout4);
            app.FilterPointSourcesPanel.Title = 'Filter Point Sources';
            app.GridLayout = uigridlayout(app.FilterPointSourcesPanel);
            app.DistancetoInjectionButton = uibutton(app.GridLayout, 'state');
            app.DistancetoInjectionButton.Tooltip = {'Select to filter point sources by distance to injection site'};
            app.DistancetoInjectionButton.Text = 'Distance to Injection';
            app.NaturalHazardRiskPntSrcButton = uibutton(app.GridLayout, 'state');
            app.NaturalHazardRiskPntSrcButton.Tooltip = {'Click to filter point sources by natural hazard risk'};
            app.NaturalHazardRiskPntSrcButton.Text = 'Natural Hazard Risk';
            app.EmissionsButton = uibutton(app.GridLayout, 'state');
            app.EmissionsButton.Tooltip = {'Click to filter point sources by emissions in Mega metric tonnes of CO2 equivalent per year'};
            app.EmissionsButton.Text = 'Emissions';
            app.PopulationButton = uibutton(app.GridLayout, 'state');
            app.PopulationButton.Tooltip = {'Click to filter point sources by population'};
            app.PopulationButton.Text = 'Population';
            app.UpdatePointSourcesButton = uibutton(app.GridLayout, 'push');
            app.UpdatePointSourcesButton.ButtonPushedFcn = createCallbackFcn(app, @filter_pt_src, true);
            app.UpdatePointSourcesButton.Tooltip = {'Click to update point sources according to selected filters'};
            app.UpdatePointSourcesButton.Text = 'Update Point Sources';
            app.DropDown_NatHazPntSrc = uidropdown(app.GridLayout);
            app.DropDown_NatHazPntSrc.Items = {'None', 'Earthquake', 'Drought', 'Hurricane', 'Riverine Flooding', 'Strong Wind', 'Wildfire'};
            app.DropDown_NatHazPntSrc.Value = 'None';
            app.DropDown_NatHazLvlPntSrc = uidropdown(app.GridLayout);
            app.DropDown_NatHazLvlPntSrc.Items = {'None', 'Very Low', 'Relatively Low', 'Relatively Moderate', 'Relatively High', 'Very High'};
            app.DropDown_NatHazLvlPntSrc.Value = 'None';
            app.Edit_DistInj = uieditfield(app.GridLayout, 'text');
            app.Edit_DistInj.Value = '0';
            app.Edit_Emissions = uieditfield(app.GridLayout, 'text');
            app.Edit_Emissions.Tooltip = {'Enter lower bound in Mega metric tonnes of CO2 equivalent per year'};
            app.Edit_Emissions.Value = '0';
            app.Edit_Population = uieditfield(app.GridLayout, 'text');
            app.Edit_Population.Tooltip = {'Enter county-level population '};
            app.Edit_Population.Placeholder = '0';
            app.Edit_Population.Value = '0';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '<';
            app.milesLabel = uilabel(app.GridLayout);
            app.milesLabel.Text = 'miles';
            app.MTCO_2eaLabel = uilabel(app.GridLayout);
            app.MTCO_2eaLabel.Interpreter = 'latex';
            app.MTCO_2eaLabel.Text = 'MT CO$_2$e/a';
            app.peopleLabel = uilabel(app.GridLayout);
            app.peopleLabel.Text = 'people';
            app.Label_6 = uilabel(app.GridLayout);
            app.Label_6.Text = '>';
            app.DropDown_PopGTLT = uidropdown(app.GridLayout);
            app.DropDown_PopGTLT.Items = {'>', '<'};
            app.DropDown_PopGTLT.Value = '>';
            app.FilterVegetationIndexPanel = uipanel(app.GridLayout4);
            app.FilterVegetationIndexPanel.Title = 'Filter Vegetation Index';
            app.GridLayout2 = uigridlayout(app.FilterVegetationIndexPanel);
            app.NaturalHazardRiskNDVIButton = uibutton(app.GridLayout2, 'state');
            app.NaturalHazardRiskNDVIButton.Tooltip = {'Click to filter county NDVI data by natural hazards'};
            app.NaturalHazardRiskNDVIButton.Text = 'Natural Hazard Risk';
            app.DropDown_NatHazNDVI = uidropdown(app.GridLayout2);
            app.DropDown_NatHazNDVI.Items = {'None', 'Earthquake', 'Drought', 'Hurricane', 'Riverine Flooding', 'Strong Wind', 'Wildfire'};
            app.DropDown_NatHazNDVI.Value = 'None';
            app.DropDown_NatHazLvlNDVI = uidropdown(app.GridLayout2);
            app.DropDown_NatHazLvlNDVI.Items = {'None', 'Very Low', 'Relatively Low', 'Relatively Moderate', 'Relatively High', 'Very High'};
            app.DropDown_NatHazLvlNDVI.Value = 'None';
            app.UpdateNDVIButton = uibutton(app.GridLayout2, 'push');
            app.UpdateNDVIButton.ButtonPushedFcn = createCallbackFcn(app, @filter_NDVI, true);
            app.UpdateNDVIButton.Tooltip = {'Click to update county NDVI by natural hazard risk'};
            app.UpdateNDVIButton.Text = 'Update NDVI';
            app.CheckproximitytoDACPanel = uipanel(app.GridLayout4);
            app.CheckproximitytoDACPanel.Title = 'Check proximity to DAC';
            app.GridLayout2_2 = uigridlayout(app.CheckproximitytoDACPanel);
            app.Edit_lon = uieditfield(app.GridLayout2_2, 'text');
            app.Edit_lon.Tooltip = {'Enter longitude from -180 to 180 [Note degrees West is negative]'};
            app.Edit_rad = uieditfield(app.GridLayout2_2, 'text');
            app.radiusmiLabel = uilabel(app.GridLayout2_2);
            app.radiusmiLabel.Text = {'radius '; '[mi]'};
            app.lonoLabel = uilabel(app.GridLayout2_2);
            app.lonoLabel.Interpreter = 'latex';
            app.lonoLabel.Text = 'lon [$^o$]';
            app.Edit_lat = uieditfield(app.GridLayout2_2, 'text');
            app.Edit_lat.Tooltip = {'Enter degree latitude from -90 to 90'};
            app.latoLabel = uilabel(app.GridLayout2_2);
            app.latoLabel.Interpreter = 'latex';
            app.latoLabel.Text = 'lat [$^o$]';
            app.ClearButton = uibutton(app.GridLayout2_2, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearCircle, true);
            app.ClearButton.Text = 'Clear';
            app.CheckLocationButton = uibutton(app.GridLayout2_2, 'push');
            app.CheckLocationButton.ButtonPushedFcn = createCallbackFcn(app, @check_location, true);
            app.CheckLocationButton.Text = {'Check'; 'Location'};
            app.DataPanel = uipanel(app.GridLayout4);
            app.DataPanel.Title = 'Data Layers';
            app.GridLayout7 = uigridlayout(app.DataPanel);
            app.DataTree = uitree(app.GridLayout7, 'checkbox');
            app.PointSourcesNode = uitreenode(app.DataTree);
            app.PointSourcesNode.Text = 'Point Sources';
            app.PowerPlantsNode = uitreenode(app.PointSourcesNode);
            app.PowerPlantsNode.Text = 'Power Plants';
            app.CementPlantsNode = uitreenode(app.PointSourcesNode);
            app.CementPlantsNode.Text = 'Cement Plants';
            app.EthanolPlantsNode = uitreenode(app.PointSourcesNode);
            app.EthanolPlantsNode.Text = 'Ethanol Plants';
            app.IronandSteelPlantsNode = uitreenode(app.PointSourcesNode);
            app.IronandSteelPlantsNode.Text = 'Iron and Steel Plants';
            app.CCSInfrastructureNode = uitreenode(app.DataTree);
            app.CCSInfrastructureNode.Text = 'CCS Infrastructure';
            app.PipelinesNode = uitreenode(app.CCSInfrastructureNode);
            app.PipelinesNode.Text = 'Pipelines';
            app.OperationalPipelinesNode = uitreenode(app.PipelinesNode);
            app.OperationalPipelinesNode.Text = 'Operational Pipelines';
            app.PlannedPipelinesNode = uitreenode(app.PipelinesNode);
            app.PlannedPipelinesNode.Text = 'Planned Pipelines';
            app.InjectionsitesNode = uitreenode(app.CCSInfrastructureNode);
            app.InjectionsitesNode.Text = 'Injection sites';
            app.GeologicalreservoirsNode = uitreenode(app.CCSInfrastructureNode);
            app.GeologicalreservoirsNode.Text = 'Geological reservoirs';
            app.NaturalHazardsNode = uitreenode(app.DataTree);
            app.NaturalHazardsNode.Text = 'Natural Hazards';
            app.PresentNode = uitreenode(app.NaturalHazardsNode);
            app.PresentNode.Text = 'Present';
            app.EarthquakeNode = uitreenode(app.PresentNode);
            app.EarthquakeNode.Text = 'Earthquake';
            app.DroughtNode = uitreenode(app.PresentNode);
            app.DroughtNode.Text = 'Drought';
            app.HurricaneNode = uitreenode(app.PresentNode);
            app.HurricaneNode.Text = 'Hurricane';
            app.RiverineFloodingNode = uitreenode(app.PresentNode);
            app.RiverineFloodingNode.Text = 'Riverine Flooding';
            app.StrongWindNode = uitreenode(app.PresentNode);
            app.StrongWindNode.Text = 'Strong Wind';
            app.WildfireNode = uitreenode(app.PresentNode);
            app.WildfireNode.Text = 'Wildfire';
            app.Projected2050Node = uitreenode(app.NaturalHazardsNode);
            app.Projected2050Node.Text = 'Projected ~2050';
            app.MaxSummerDailyAvgTemperatureFNode = uitreenode(app.Projected2050Node);
            app.MaxSummerDailyAvgTemperatureFNode.Text = 'Max Summer Daily Avg Temperature [F]';
            app.RCP85Node_2 = uitreenode(app.MaxSummerDailyAvgTemperatureFNode);
            app.RCP85Node_2.Text = 'RCP 8.5';
            app.ConsecutiveDayswithoutPrecipitationNode = uitreenode(app.Projected2050Node);
            app.ConsecutiveDayswithoutPrecipitationNode.Text = 'Consecutive Days without Precipitation';
            app.RCP45Node = uitreenode(app.ConsecutiveDayswithoutPrecipitationNode);
            app.RCP45Node.Text = 'RCP 4.5';
            app.RCP85Node = uitreenode(app.ConsecutiveDayswithoutPrecipitationNode);
            app.RCP85Node.Text = 'RCP 8.5';
            app.TotalAnnualPrecipitationinNode = uitreenode(app.Projected2050Node);
            app.TotalAnnualPrecipitationinNode.Text = 'Total Annual Precipitation [in]';
            app.RCP45Node_2 = uitreenode(app.TotalAnnualPrecipitationinNode);
            app.RCP45Node_2.Text = 'RCP 4.5';
            app.RCP85Node_3 = uitreenode(app.TotalAnnualPrecipitationinNode);
            app.RCP85Node_3.Text = 'RCP 8.5';
            app.GridCarbonIntensitylbsCO2MWhNode = uitreenode(app.DataTree);
            app.GridCarbonIntensitylbsCO2MWhNode.Text = 'Grid Carbon Intensity [lbs CO2/MWh]';
            app.current2021Node = uitreenode(app.GridCarbonIntensitylbsCO2MWhNode);
            app.current2021Node.Text = 'current (2021)';
            app.projected2050Node = uitreenode(app.GridCarbonIntensitylbsCO2MWhNode);
            app.projected2050Node.Text = 'projected (2050)';
            app.NDVINode = uitreenode(app.DataTree);
            app.NDVINode.Text = 'NDVI';
            app.SocialVulnerabilityNode = uitreenode(app.DataTree);
            app.SocialVulnerabilityNode.Text = 'Social Vulnerability';
            app.CommunityResilienceNode = uitreenode(app.DataTree);
            app.CommunityResilienceNode.Text = 'Community Resilience';
            app.PopulationNode = uitreenode(app.DataTree);
            app.PopulationNode.Text = 'Population';
            app.InterstatesNode = uitreenode(app.DataTree);
            app.InterstatesNode.Text = 'Interstates';
            app.RailNode = uitreenode(app.DataTree);
            app.RailNode.Text = 'Rail';
            app.ElectrictransmissionlinesNode = uitreenode(app.DataTree);
            app.ElectrictransmissionlinesNode.Text = 'Electric transmission lines';
            app.DisadvantagedCommunitiesNode = uitreenode(app.DataTree);
            app.DisadvantagedCommunitiesNode.Text = 'Disadvantaged Communities';
            app.DACCEQNode = uitreenode(app.DisadvantagedCommunitiesNode);
            app.DACCEQNode.Text = 'DAC (CEQ)';
            app.DACtribaloverlapCEQNode = uitreenode(app.DisadvantagedCommunitiesNode);
            app.DACtribaloverlapCEQNode.Text = 'DAC, tribal overlap (CEQ)';
            app.DACScoreDOENode = uitreenode(app.DisadvantagedCommunitiesNode);
            app.DACScoreDOENode.Text = 'DAC Score (DOE)';
            app.TCTACsNode = uitreenode(app.DataTree);
            app.TCTACsNode.Text = 'TCTACs';
            app.TCTACRegionsNode = uitreenode(app.TCTACsNode);
            app.TCTACRegionsNode.Text = 'TCTAC Regions';
            app.TCTACAwardeesNode = uitreenode(app.TCTACsNode);
            app.TCTACAwardeesNode.Text = 'TCTAC Awardees';
            app.TCTACPartnersNode = uitreenode(app.TCTACsNode);
            app.TCTACPartnersNode.Text = 'TCTAC Partners';
            % Assign Checked Nodes
            app.DataTree.CheckedNodesChangedFcn = createCallbackFcn(app, @checkchange, true);
            app.DataLayersTab = uitab(app.TabGroup);
            app.DataLayersTab.Title = 'Data Layers';
            app.GridLayout5 = uigridlayout(app.DataLayersTab);
            app.DataLayersPanel = uipanel(app.GridLayout5);
            app.DataLayersPanel.Title = 'Data Layers';
            app.GridLayout6 = uigridlayout(app.DataLayersPanel);
            app.Tree = uitree(app.GridLayout6);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.PointSourcesNode_2 = uitreenode(app.Tree);
            app.PointSourcesNode_2.Text = 'Point Sources';
            app.PowerPlantsNode_2 = uitreenode(app.PointSourcesNode_2);
            app.PowerPlantsNode_2.Text = 'Power Plants';
            app.CementPlantsNode_2 = uitreenode(app.PointSourcesNode_2);
            app.CementPlantsNode_2.Text = 'Cement Plants';
            app.EthanolPlantsNode_2 = uitreenode(app.PointSourcesNode_2);
            app.EthanolPlantsNode_2.Text = 'Ethanol Plants';
            app.IronandSteelPlantsNode_2 = uitreenode(app.PointSourcesNode_2);
            app.IronandSteelPlantsNode_2.Text = 'Iron and Steel Plants';
            app.CCSInfrastructureNode_2 = uitreenode(app.Tree);
            app.CCSInfrastructureNode_2.Text = 'CCS Infrastructure';
            app.PipelinesNode_2 = uitreenode(app.CCSInfrastructureNode_2);
            app.PipelinesNode_2.Text = 'Pipelines';
            app.OperationalNode = uitreenode(app.PipelinesNode_2);
            app.OperationalNode.Text = 'Operational';
            app.PlannedNode = uitreenode(app.PipelinesNode_2);
            app.PlannedNode.Text = 'Planned';
            app.InjectionSitesNode = uitreenode(app.CCSInfrastructureNode_2);
            app.InjectionSitesNode.Text = 'Injection Sites';
            app.GeologicalReservoirsNode = uitreenode(app.CCSInfrastructureNode_2);
            app.GeologicalReservoirsNode.Text = 'Geological Reservoirs';
            app.NaturalHazardsNode_2 = uitreenode(app.Tree);
            app.NaturalHazardsNode_2.Text = 'Natural Hazards';
            app.PresentNode_2 = uitreenode(app.NaturalHazardsNode_2);
            app.PresentNode_2.Text = 'Present';
            app.EarthquakeNode_2 = uitreenode(app.PresentNode_2);
            app.EarthquakeNode_2.Text = 'Earthquake';
            app.DroughtNode_2 = uitreenode(app.PresentNode_2);
            app.DroughtNode_2.Text = 'Drought';
            app.HurricaneNode_2 = uitreenode(app.PresentNode_2);
            app.HurricaneNode_2.Text = 'Hurricane';
            app.RiverineFloodingNode_2 = uitreenode(app.PresentNode_2);
            app.RiverineFloodingNode_2.Text = 'Riverine Flooding';
            app.StrongWindNode_2 = uitreenode(app.PresentNode_2);
            app.StrongWindNode_2.Text = 'Strong Wind';
            app.WildfireNode_2 = uitreenode(app.PresentNode_2);
            app.WildfireNode_2.Text = 'Wildfire';
            app.Projected2050Node_2 = uitreenode(app.NaturalHazardsNode_2);
            app.Projected2050Node_2.Text = 'Projected ~2050';
            app.MaxSummerDailyAvgTemperatureNode = uitreenode(app.Projected2050Node_2);
            app.MaxSummerDailyAvgTemperatureNode.Text = 'Max Summer Daily Avg Temperature';
            app.RCP85Node_4 = uitreenode(app.MaxSummerDailyAvgTemperatureNode);
            app.RCP85Node_4.Text = 'RCP 8.5';
            app.ConsecutiveDayswithoutPrecipitationNode_2 = uitreenode(app.Projected2050Node_2);
            app.ConsecutiveDayswithoutPrecipitationNode_2.Text = 'Consecutive Days without Precipitation';
            app.RCP45Node_3 = uitreenode(app.ConsecutiveDayswithoutPrecipitationNode_2);
            app.RCP45Node_3.Text = 'RCP 4.5';
            app.RCP85Node_5 = uitreenode(app.ConsecutiveDayswithoutPrecipitationNode_2);
            app.RCP85Node_5.Text = 'RCP 8.5';
            app.TotalAnnualPrecipitationNode = uitreenode(app.Projected2050Node_2);
            app.TotalAnnualPrecipitationNode.Text = 'Total Annual Precipitation';
            app.RCP45Node_4 = uitreenode(app.TotalAnnualPrecipitationNode);
            app.RCP45Node_4.Text = 'RCP 4.5';
            app.RCP85Node_6 = uitreenode(app.TotalAnnualPrecipitationNode);
            app.RCP85Node_6.Text = 'RCP 8.5';
            app.GridCarbonIntensitylbsCO2MWhNode_2 = uitreenode(app.Tree);
            app.GridCarbonIntensitylbsCO2MWhNode_2.Text = 'Grid Carbon Intensity [lbs CO2/MWh]';
            app.PresentNode_3 = uitreenode(app.GridCarbonIntensitylbsCO2MWhNode_2);
            app.PresentNode_3.Text = 'Present';
            app.Projected2050Node_3 = uitreenode(app.GridCarbonIntensitylbsCO2MWhNode_2);
            app.Projected2050Node_3.Text = 'Projected ~2050';
            app.NDVINode_2 = uitreenode(app.Tree);
            app.NDVINode_2.Text = 'NDVI';
            app.SocialVulnerabilityNode_2 = uitreenode(app.Tree);
            app.SocialVulnerabilityNode_2.Text = 'Social Vulnerability';
            app.CommunityResilienceNode_2 = uitreenode(app.Tree);
            app.CommunityResilienceNode_2.Text = 'Community Resilience';
            app.PopulationNode_2 = uitreenode(app.Tree);
            app.PopulationNode_2.Text = 'Population';
            app.InterstatesNode_2 = uitreenode(app.Tree);
            app.InterstatesNode_2.Text = 'Interstates';
            app.RailNode_2 = uitreenode(app.Tree);
            app.RailNode_2.Text = 'Rail';
            app.DisadvantagedCommunitiesNode_2 = uitreenode(app.Tree);
            app.DisadvantagedCommunitiesNode_2.Text = 'Disadvantaged Communities';
            app.DACCEQNode_2 = uitreenode(app.DisadvantagedCommunitiesNode_2);
            app.DACCEQNode_2.Text = 'DAC (CEQ)';
            app.DACtribaloverlapCEQNode_2 = uitreenode(app.DisadvantagedCommunitiesNode_2);
            app.DACtribaloverlapCEQNode_2.Text = 'DAC, tribal overlap (CEQ)';
            app.DACScoreDOENode_2 = uitreenode(app.DisadvantagedCommunitiesNode_2);
            app.DACScoreDOENode_2.Text = 'DAC Score (DOE)';
            app.TCTACsNode_2 = uitreenode(app.Tree);
            app.TCTACsNode_2.Text = 'TCTACs';
            app.TCTACRegionsNode_2 = uitreenode(app.TCTACsNode_2);
            app.TCTACRegionsNode_2.Text = 'TCTAC Regions';
            app.TCTACAwardeesNode_2 = uitreenode(app.TCTACsNode_2);
            app.TCTACAwardeesNode_2.Text = 'TCTAC Awardees';
            app.TCTACPartnersNode_2 = uitreenode(app.TCTACsNode_2);
            app.TCTACPartnersNode_2.Text = 'TCTAC Partners';
            app.SourcesPanel = uipanel(app.GridLayout5);
            app.SourcesPanel.Title = 'Sources';
            app.GridLayout8 = uigridlayout(app.SourcesPanel);
            app.TextArea = uitextarea(app.GridLayout8);
            app.TextArea.Editable = 'off';
            app.TextArea.Placeholder = 'To learn more about the datasets, please select a data layer from the tree.';
            app.DACTab = uitab(app.TabGroup);
            app.DACTab.Title = 'DAC';
            app.GridLayout9 = uigridlayout(app.DACTab);
            app.Label_7 = uilabel(app.GridLayout9);
            app.Label_7.Text = '';
            app.DAC_printout = uitextarea(app.GridLayout9);
            app.DAC_printout.Editable = 'off';
            app.FreezemapHoverforDACdetailsButton = uibutton(app.GridLayout9, 'state');
            app.FreezemapHoverforDACdetailsButton.ValueChangedFcn = createCallbackFcn(app, @FreezemapHoverforDACdetailsButtonValueChanged, true);
            app.FreezemapHoverforDACdetailsButton.Text = {'Freeze map,'; 'Hover for DAC details'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ResilienceApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end